classdef PuzzleSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PuzzleSolverUIFigure   matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        EditField_2            matlab.ui.control.NumericEditField
        EditField_3            matlab.ui.control.NumericEditField
        EditField              matlab.ui.control.NumericEditField
        EditField_4            matlab.ui.control.NumericEditField
        EditField_5            matlab.ui.control.NumericEditField
        EditField_6            matlab.ui.control.NumericEditField
        EditField_7            matlab.ui.control.NumericEditField
        EditField_8            matlab.ui.control.NumericEditField
        EditField_9            matlab.ui.control.NumericEditField
        PuzzleSolverLabel      matlab.ui.control.Label
        SolveButton            matlab.ui.control.Button
        CommentsTextAreaLabel  matlab.ui.control.Label
        CommentsTextArea       matlab.ui.control.TextArea
        ReplayButton           matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
    end


    properties (Access = private)
        cellPos = [10 190 80 80; 10 100 80 80; 10 10 80 80;...
                   100 190 80 80; 100 100 80 80; 100 10 80 80;...
                   190 190 80 80; 190 100 80 80; 190 10 80 80];
        goalNode = ones(3, 3)*-1;
        initialNode = ones(3, 3)*-1;
        movement = [1 0; 0 1; -1 0; 0 -1]; % [up, right, down, left]
        nodeConverter = [10^8; 10^7; 10^6; 10^5; 10^4; 10^3; 10^2; 10^1; 10^0];
        nodePath
        NodeSet = struct('Nodes', [], 'NodesInfo', []);
        zeroField
    end

    methods (Access = private)
        % Identify the fields at each location.
        function field = identifyField(app, num)
            pos = app.cellPos(num,:);
            if all(app.EditField.Position == pos)
                field = app.EditField;
            elseif all(app.EditField_2.Position == pos)
                field = app.EditField_2;
            elseif all(app.EditField_3.Position == pos)
                field = app.EditField_3;
            elseif all(app.EditField_4.Position == pos)
                field = app.EditField_4;
            elseif all(app.EditField_5.Position == pos)
                field = app.EditField_5;
            elseif all(app.EditField_6.Position == pos)
                field = app.EditField_6;
            elseif all(app.EditField_7.Position == pos)
                field = app.EditField_7;
            elseif all(app.EditField_8.Position == pos)
                field = app.EditField_8;
            elseif all(app.EditField_9.Position == pos)
                field = app.EditField_9;
            end
        end
        
        % This function checks whether the puzzle is solvable for given initial node and goal node.
        function status = isSolvable(app)
            % Reshape the matrices into row matrices
            initNode = reshape(app.initialNode',[1 9]);
            finalNode = reshape(app.goalNode',[1 9]);
            
            % Count the number of inversions
            count = 0;
            for i = 1:8
                for j = i+1:9
                    if all(initNode([i,j]) ~= 0) && (find(finalNode == initNode(i)) > find(finalNode == initNode(j)))
                        count = count + 1;
                    end
                end
            end
            
            % Check if the number of inversions are even or not
            if rem(count,2) ~= 0
                status = false;
            else
                status = true;
            end
        end
        
        % This function assigns a unique number to the given node
        function state = nodeNumber(app, node)
            node = reshape(node', [1, 9]);
            state = node*app.nodeConverter;
        end
        
        % This function shifts the blank space in all the directions possible
        function [status, newNode, newNodeNum] = moveBlankTile(app, currentNode)
            status = false(4,1);
            newNode = zeros(3,3,4);
            newNodeNum = zeros(4,1);
            [row, col] = find(currentNode == 0);
            for i = 1:4
                newPos = [row, col] + app.movement(i,:);
                status(i) = all(newPos > 0) && all(newPos < 4);
                if status(i)
                    newNode(:,:,i) = currentNode;
                    newNode(row,col,i) = newNode(newPos(1),newPos(2),i);
                    newNode(newPos(1),newPos(2),i) = 0;
                    newNodeNum(i) = nodeNumber(app, newNode(:,:,i));
                end
            end
        end
        
        % This function takes the initial node and goal node as input,
        % and outputs a struct containing all explored nodes.
        function nodesGenerator(app)
            % Initialize all variables
            Nodes = zeros(3, 3, factorial(9));
            Nodes(:,:,1) = app.initialNode;
            NodesInfo = zeros(factorial(9), 3); % NodesInfo = [Node #, Parent node #, CostToCome]
            NodesInfo(1,:) = [1 0 0];
            NodesNumList = containers.Map(nodeNumber(app, app.initialNode), 1);
            goalNodeNum = nodeNumber(app, app.goalNode);
            
            % Start exploration
            currNodeIndex = 0;
            newNodeIndex = 1;
            oldCost = 0;
            while currNodeIndex < NodesNumList.Count
                currNodeIndex = currNodeIndex + 1;
                c2c = NodesInfo(currNodeIndex,3) + 1;
                if c2c > oldCost
                    app.CommentsTextArea.Value = {['Currently at Layer ' num2str(c2c) ' of the Search Tree']};
                    pause(0.01)
                    oldCost = c2c;
                end
                % Move the blank space
                [status, newNode, newNodeNum] = moveBlankTile(app, Nodes(:,:,currNodeIndex));
                for i = 1:4
                    if status(i) && ~NodesNumList.isKey(newNodeNum(i))
                        newNodeIndex = newNodeIndex + 1;
                        NodesNumList(newNodeNum(i)) = newNodeIndex;
                        Nodes(:,:,newNodeIndex) = newNode(:,:,i);
                        NodesInfo(newNodeIndex,:) = [newNodeIndex, currNodeIndex, c2c];
                        if newNodeNum(i) == goalNodeNum
                            currNodeIndex = inf;
                            break;
                        end
                    end
                end
            end
            
            % Create struct
            app.NodeSet.Nodes = Nodes(:,:,1:newNodeIndex);
            app.NodeSet.NodesInfo = NodesInfo(1:newNodeIndex,:);
        end
        
        % This function reaches the goal node from the initial node
        function reachGoal(app)
            % Initialize all variables
            app.nodePath = zeros(3, 3, app.NodeSet.NodesInfo(end,3) + 1);
            app.nodePath(:,:,end) = app.NodeSet.Nodes(:,:,end);
            parentNodePos = app.NodeSet.NodesInfo(end,2);
            
            % Start developing the path
            for i = size(app.nodePath, 3)-1:-1:1
                app.nodePath(:,:,i) = app.NodeSet.Nodes(:,:,parentNodePos);
                parentNodePos = app.NodeSet.NodesInfo(parentNodePos,2);
            end
        end
            
        % This function plots the puzzle solver using node path
        function plotPath(app)
            totalSteps = num2str(size(app.nodePath,3) - 1);
            currStep = 1;
            currentNode = app.nodePath(:,:,1);
            nextBlank = find(currentNode == 0);
            for n = 2:size(app.nodePath,3)
                currentBlank = nextBlank;
                nextNode = app.nodePath(:,:,n);
                nextBlank = find(nextNode == 0);
                changePos = [app.cellPos(nextBlank,:); app.cellPos(currentBlank,:)];
                traverse = zeros(100,4);
                for i = 1:4
                    traverse(:,i) = linspace(changePos(1,i),changePos(2,i))';
                end
                field = identifyField(app, nextBlank);
                app.CommentsTextArea.Value = {['Executing step ' num2str(currStep) ' out of ' totalSteps ' steps.']};
                pause(0.01);
                for t = 1:100
                    field.Position = traverse(t,:);
                    app.zeroField.Position = traverse(end-t+1,:);
                    pause(0.01);
                end
                currStep = currStep + 1;
            end
        end
    end

    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            pause on
            app.CommentsTextArea.FontSize = 14;
            
            % Read the goal state
            for i = 1:9
                field = identifyField(app, i);
                if any(app.goalNode == field.Value, 'All')
                    app.CommentsTextArea.Value = {'The goal state is invalid. It should have unique values ranging from 0 to 8.'};
                    app.goalNode = ones(3,3)*-1;
                    return;
                end
                app.goalNode(i) = field.Value;
            end
            
            % Check if a solution exists
            if ~isSolvable(app)
                app.CommentsTextArea.Value = {'No solution exists for the given set of initial node and goal node.'};
                pause(5)
                ResetButtonPushed(app, event);
                return;
            end
            
            % Reset the puzzle to initial node
            for i = 1:9
                field = identifyField(app, i);
                field.Value = app.initialNode(i);
            end
            
            % Generate nodes
            app.CommentsTextArea.Value = {'Generating all possible nodes from initial node to goal node.'};
            pause(5)
            nodesGenerator(app)
            app.CommentsTextArea.Value = {'Node exploration completed successfully.'};
            pause(5)
            
            % Generate the path
            app.CommentsTextArea.Value = {'Generating the desired path.'};
            pause(5)
            reachGoal(app)
            app.CommentsTextArea.Value = {'Path from the initial node to the goal node generated successfully.'};
            pause(5)
            
            % Start solving the puzzle
            blankPos = find(app.initialNode == 0);
            app.zeroField = identifyField(app, blankPos);
            app.zeroField.Visible = 'off';
            app.CommentsTextArea.Value = {'Solving the puzzle.'};
            pause(5)
            plotPath(app)
            app.CommentsTextArea.Value = {'Goal node successfully reached.'};
            app.SolveButton.Visible = 'off';
            app.ReplayButton.Visible = 'on';
            app.ResetButton.Visible = 'on';
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            app.CommentsTextArea.Value = {'Replaying the simulation.'};
            pause(5)
            
            % Reset the puzzle to initial node
            app.zeroField.Visible = 'on';
            for i = 1:9
                field = identifyField(app, i);
                field.Value = app.initialNode(i);
            end
            
            % Start solving the puzzle
            blankPos = find(app.initialNode == 0);
            app.zeroField = identifyField(app, blankPos);
            app.zeroField.Visible = 'off';
            app.CommentsTextArea.Value = {'Solving the puzzle.'};
            pause(5)
            plotPath(app)
            app.CommentsTextArea.Value = {'Goal node successfully reached.'};
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CommentsTextArea.Value = {'Set the initial state.'};
            app.CommentsTextArea.FontSize = 18;
            app.goalNode = ones(3,3)*-1;
            
            % Reset the puzzle to initial node
            for i = 1:9
                field = identifyField(app, i);
                field.Value = app.initialNode(i);
            end
            app.initialNode = ones(3,3)*-1;
            
            app.NextButton.Visible = 'on';
            app.ReplayButton.Visible = 'off';
            app.ResetButton.Visible = 'off';
            app.SolveButton.Visible = 'off';
            app.zeroField.Visible = 'on';
            app.nodePath = [];
            app.NodeSet.Nodes = [];
            app.NodeSet.NodesInfo = [];
            app.zeroField = [];
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.CommentsTextArea.FontSize = 14;
            for i = 1:9
                field = identifyField(app, i);
                if any(app.initialNode == field.Value, 'All')
                    app.CommentsTextArea.Value = {'The initial state is invalid. It should have unique values ranging from 0 to 8.'};
                    app.initialNode = ones(3,3)*-1;
                    return;
                end
                app.initialNode(i) = field.Value;
            end
            app.CommentsTextArea.FontSize = 18;
            app.NextButton.Visible = 'off';
            app.SolveButton.Visible = 'on';
            app.CommentsTextArea.Value = {'Set the goal state.'};
            goalState = [1 2 3; 4 5 6; 7 8 0];
            for i = 1:9
                field = identifyField(app, i);
                field.Value = goalState(i);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PuzzleSolverUIFigure = uifigure;
            app.PuzzleSolverUIFigure.Name = 'Puzzle-Solver';
            app.Panel = uipanel(app.PuzzleSolverUIFigure);
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Limits = [0 8];
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.Value = 2;
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Limits = [0 8];
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.EditField_3.Value = 5;
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 8];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Value = 3;
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.Limits = [0 8];
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.Value = 1;
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_5.Limits = [0 8];
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.ValueDisplayFormat = '%.0f';
            app.EditField_6 = uieditfield(app.Panel, 'numeric');
            app.EditField_6.Limits = [0 8];
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.EditField_6.Value = 6;
            app.EditField_7 = uieditfield(app.Panel, 'numeric');
            app.EditField_7.Limits = [0 8];
            app.EditField_7.RoundFractionalValues = 'on';
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_7.Value = 4;
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.EditField_8.Limits = [0 8];
            app.EditField_8.RoundFractionalValues = 'on';
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.Value = 7;
            app.EditField_9 = uieditfield(app.Panel, 'numeric');
            app.EditField_9.Limits = [0 8];
            app.EditField_9.RoundFractionalValues = 'on';
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_9.Value = 8;
            app.PuzzleSolverLabel = uilabel(app.PuzzleSolverUIFigure);
            app.PuzzleSolverLabel.Text = 'Puzzle Solver';
            app.SolveButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Visible = 'off';
            app.SolveButton.Text = 'Solve';
            app.CommentsTextAreaLabel = uilabel(app.PuzzleSolverUIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.PuzzleSolverUIFigure);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Value = {'Set the initial state.'};
            app.ReplayButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Visible = 'off';
            app.ReplayButton.Text = 'Replay';
            app.ResetButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.NextButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CloseButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PuzzleSolver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PuzzleSolverUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PuzzleSolverUIFigure)
        end
    end
end